classdef lagerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ReifenLabel_2          matlab.ui.control.Label
        FelgenLabel_2          matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        DefektLabel            matlab.ui.control.Label
        KaputtesTeilSwitch     matlab.ui.control.Switch
        ReifenLabel            matlab.ui.control.Label
        FelgenLabel            matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        LagerLabel             matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        ProgrammluftLamp       matlab.ui.control.Lamp
        ProgrammluftLampLabel  matlab.ui.control.Label
        AuswahlButtonGroup     matlab.ui.container.ButtonGroup
        ReifenButton           matlab.ui.control.RadioButton
        FelgeButton            matlab.ui.control.RadioButton
        StartButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Initialisierung der Variablen und Verbindung mit dem Brick
            global item;
            global started;
            global brick;
            global storage;
            global state;
            global broken;
            app.UIFigure.Name = 'Connecting';
            state = 'Holen';
            brick = EV3();
            brick.connect("bt", "serPort", "/dev/rfcomm0");
            started = 0;
            item = 1;
            storage = [3, 3];
            broken = [0, 0];
            app.UIFigure.Name = 'MO';
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Startfunktion
            global started;
            global item;
            global brick;
            global storage;
            global broken;
            global state;
            %Starten, falls noch nicht läuft
            currentItem = item;
            if started == 0
                started = 1;
                
                %Kein Objekt des gewünschten typ mehr vorhanden ->
                %Fehlerton
                if storage(currentItem) == 0
                    brick.playTone(40, 200, 500);
                    app.ProgrammluftLamp.Color = [1, 0, 0];
                    pause(2);
                    app.ProgrammluftLamp.Color = [0.651, 0.651, 0.651];
                else
                    %lampe auf grün setzen und main starten
                    app.ProgrammluftLamp.Color = [0, 1, 0];
                    success = main(brick, item, storage, state);
                    %Falls Durchlauf erfolgreich, Counter aktualisieren,
                    %sonst neu verbinden
                    if success
                        if strcmp(state, 'Holen')
                            storage(currentItem) = storage(currentItem) - 1;
                        else
                            broken(currentItem) = broken(currentItem) +1;
                        end
                    else
                        app.ProgrammluftLamp.Color = [1, 0, 0];
                        brick.connect("bt", "serPort", "/dev/rfcomm0");
                        pause(5);
                    end
                    %GUI aktualisieren
                    app.ProgrammluftLamp.Color = [0.651, 0.651, 0.651];
                    app.Label.Text = num2str(storage(1));
                    app.Label_2.Text = num2str(storage(2));
                    app.Label_3.Text = num2str(broken(1));
                    app.Label_4.Text = num2str(broken(2));
                end
                started = 0;
            end
        end

        % Selection changed function: AuswahlButtonGroup
        function AuswahlButtonGroupSelectionChanged(app, event)
            %Gewünschtes Item einstellen
            selectedButton = app.AuswahlButtonGroup.SelectedObject;
            global item;
            switch selectedButton.Text
                case 'Felge'
                    item = 1;
                case 'Reifen'
                    item = 2;
            end
            disp("Wert geändert")
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %Programm stoppen, durch schließen der Verbindung (Fehler erzeugen)
            global brick
            app.ProgrammluftLamp.Color = [1, 0, 0];
            brick.motorA.stop();
            brick.motorB.stop();
            brick.motorC.stop();
            brick.motorD.stop();
            brick.disconnect();
            disp("Vorgang wurde abgebrochen!")
        end

        % Value changed function: KaputtesTeilSwitch
        function KaputtesTeilSwitchValueChanged(app, event)
            %Holen oder Bringen einstellen
            value = app.KaputtesTeilSwitch.Value;
            global state;
            state = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %GUI und Verbindung schließen
            delete(app)
            global brick;
            brick.disconnect();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AuswahlButtonGroup = uibuttongroup(app.UIFigure);
            app.AuswahlButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AuswahlButtonGroupSelectionChanged, true);
            app.AuswahlButtonGroup.Title = 'Auswahl';
            app.FelgeButton = uiradiobutton(app.AuswahlButtonGroup);
            app.FelgeButton.Text = 'Felge';
            app.FelgeButton.Value = true;
            app.ReifenButton = uiradiobutton(app.AuswahlButtonGroup);
            app.ReifenButton.Text = 'Reifen';
            app.ProgrammluftLampLabel = uilabel(app.UIFigure);
            app.ProgrammluftLampLabel.Text = 'Programm läuft';
            app.ProgrammluftLamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LagerLabel = uilabel(app.UIFigure);
            app.LagerLabel.Text = 'Lager';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '3';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '3';
            app.FelgenLabel = uilabel(app.UIFigure);
            app.FelgenLabel.Text = 'Felgen';
            app.ReifenLabel = uilabel(app.UIFigure);
            app.ReifenLabel.Text = 'Reifen';
            app.KaputtesTeilSwitch = uiswitch(app.UIFigure, 'slider');
            app.KaputtesTeilSwitch.Items = {'Holen', 'Bringen'};
            app.KaputtesTeilSwitch.ValueChangedFcn = createCallbackFcn(app, @KaputtesTeilSwitchValueChanged, true);
            app.KaputtesTeilSwitch.Value = 'Holen';
            app.DefektLabel = uilabel(app.UIFigure);
            app.DefektLabel.Text = {'Defekt'; ''};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '0';
            app.FelgenLabel_2 = uilabel(app.UIFigure);
            app.FelgenLabel_2.Text = 'Felgen';
            app.ReifenLabel_2 = uilabel(app.UIFigure);
            app.ReifenLabel_2.Text = 'Reifen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lagerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end